classdef AndorDMSV2_LSL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AcquisitionTab                 matlab.ui.container.Tab
        SaveButton_2                   matlab.ui.control.Button
        VBinSpinner                    matlab.ui.control.Spinner
        VBinSpinnerLabel               matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        LiveModeSwitch                 matlab.ui.control.Switch
        LiveModeSwitchLabel            matlab.ui.control.Label
        GetBGButton                    matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        SetButton                      matlab.ui.control.Button
        HBinSpinner                    matlab.ui.control.Spinner
        HBinSpinnerLabel               matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        CoolerSwitch                   matlab.ui.control.Switch
        CoolerSwitchLabel              matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        GetTempButton                  matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        SetTempButton                  matlab.ui.control.Button
        TemperatureSpinner             matlab.ui.control.Spinner
        TemperatureSpinnerLabel        matlab.ui.control.Label
        CurrentTemperatureGauge        matlab.ui.control.SemicircularGauge
        CurrentTemperatureGaugeLabel   matlab.ui.control.Label
        RandomTrackLabel               matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        ShutterCloseSpinner            matlab.ui.control.Spinner
        ShutterCloseSpinnerLabel       matlab.ui.control.Label
        ShutterOpenSpinner             matlab.ui.control.Spinner
        ShutterOpenSpinnerLabel        matlab.ui.control.Label
        ExposuresSpinner               matlab.ui.control.Spinner
        ExposuresSpinnerLabel          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        SettingsTab                    matlab.ui.container.Tab
        FilePanel                      matlab.ui.container.Panel
        OpenButton                     matlab.ui.control.Button
        TargetFolderEditField          matlab.ui.control.EditField
        TargetFolderEditFieldLabel     matlab.ui.control.Label
        LaserPanel                     matlab.ui.container.Panel
        RecoveryTimeminSpinner         matlab.ui.control.Spinner
        RecoveryTimeminSpinnerLabel    matlab.ui.control.Label
        TotalLaserTimeminSpinner       matlab.ui.control.Spinner
        TotalLaserTimeminSpinnerLabel  matlab.ui.control.Label
        KineticCycleTimesSpinner       matlab.ui.control.Spinner
        KineticCycleTimesSpinnerLabel  matlab.ui.control.Label
        DMSPanel                       matlab.ui.container.Panel
        TrialsSpinner                  matlab.ui.control.Spinner
        TrialsSpinnerLabel             matlab.ui.control.Label
        LayoutSpinner                  matlab.ui.control.Spinner
        LayoutSpinnerLabel             matlab.ui.control.Label
        ITISpinner                     matlab.ui.control.Spinner
        ITISpinnerLabel                matlab.ui.control.Label
        ProbeSpinner                   matlab.ui.control.Spinner
        ProbeSpinnerLabel              matlab.ui.control.Label
        RetentionSpinner               matlab.ui.control.Spinner
        RetentionSpinnerLabel          matlab.ui.control.Label
        StimulusSpinner                matlab.ui.control.Spinner
        StimulusSpinnerLabel           matlab.ui.control.Label
        KineticCycleTimesSpinner_2     matlab.ui.control.Spinner
        KineticCycleTimesSpinner_2Label  matlab.ui.control.Label
        BaselinePanel                  matlab.ui.container.Panel
        SamplesSpinner                 matlab.ui.control.Spinner
        SamplesSpinnerLabel            matlab.ui.control.Label
        CycleTimesSpinner              matlab.ui.control.Spinner
        CycleTimesSpinnerLabel         matlab.ui.control.Label
        AccumulationsSpinner           matlab.ui.control.Spinner
        AccumulationsSpinnerLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        XPixels % number of XPixels
        YPixels % number of YPixels
        loopContinue = 1; % while loop settings
        I; % acquired image data
        ima;% Axes image properties
        ntracksloc % random track location
        folderPath % path to save data
        colormap_arr
    end
    
    methods (Access = private)
        
        function DispError(app,code)
            switch(code)
                case 20001
                    warndlg('DRV_ERROR_CODES [20001]');
                case 20002
                    return
                case 20003
                    warndlg('DRV_VXDNOTINSTALLED [20003]');
                case 20004
                    warndlg('DRV_ERROR_SCAN [20004]');
                case 20005
                    warndlg('DRV_ERROR_CHECK_SUM [20005]');
                case 20006
                    warndlg('DRV_ERROR_FILELOAD [20006]');
                case 20007
                    warndlg('DRV_UNKNOWN_FUNCTION [20007]');
                case 20008
                    warndlg('DRV_ERROR_VXD_INIT [20008]');
                case 20009
                    warndlg('DRV_ERROR_ADDRESS [20009]');
                case 20010
                    warndlg('DRV_ERROR_PAGELOCK [20010]');
                case 20011
                    warndlg('DRV_ERROR_PAGEUNLOCK [20011]');
                case 20012
                    warndlg('DRV_ERROR_BOARDTEST [20012]');
                case 20013
                    warndlg('DRV_ERROR_ACK [20013]');
                case 20014
                    warndlg('DRV_ERROR_UP_FIFO [20014]');
                case 20015
                    warndlg('DRV_ERROR_PATTERN [20015]');
                case 20017
                    warndlg('DRV_ACQUISITION_ERRORS [20017]');
                case 20018
                    warndlg('DRV_ACQ_BUFFER [20018]');
                case 20019
                    warndlg('DRV_ACQ_DOWNFIFO_FULL [20019]');
                case 20020
                    warndlg('DRV_PROC_UNKONWN_INSTRUCTION [20020]');
                case 20021
                    warndlg('DRV_ILLEGAL_OP_CODE [20021]');
                case 20022
                    warndlg('DRV_KINETIC_TIME_NOT_MET [20022]');
                case 20023
                    warndlg('DRV_ACCUM_TIME_NOT_MET [20023]');
                case 20024
                    warndlg('DRV_NO_NEW_DATA [20024]');
                case 20025
                    warndlg('DRV_PCI_DMA_FAIL [20025]');
                case 20026
                    warndlg('DRV_SPOOLERROR [20026]');
                case 20027
                    warndlg('DRV_SPOOLSETUPERROR [20027]');
                case 20028
                    warndlg('DRV_FILESIZELIMITERROR [20028]');
                case 20029
                    warndlg('DRV_ERROR_FILESAVE [20029]');
                case 20033
                    warndlg('DRV_TEMPERATURE_CODES [20033]');
                case 20034
                    warndlg('DRV_TEMPERATURE_OFF [20034]');
                case 20035
                    warndlg('DRV_TEMPERATURE_NOT_STABILIZED [20035]');
                case 20036
                    warndlg('DRV_TEMPERATURE_STABILIZED [20036]');
                case 20037
                    warndlg('DRV_TEMPERATURE_NOT_REACHED [20037]');
                case 20038
                    warndlg('DRV_TEMPERATURE_OUT_RANGE [20038]');
                case 20039
                    warndlg('DRV_TEMPERATURE_NOT_SUPPORTED [20039]');
                case 20040
                    warndlg('DRV_TEMPERATURE_DRIFT [20040]');
                case 20049
                    warndlg('DRV_GENERAL_ERRORS [20049]');
                case 20050
                    warndlg('DRV_INVALID_AUX [20050]');
                case 20051
                    warndlg('DRV_COF_NOTLOADED [20051]');
                case 20052
                    warndlg('DRV_FPGAPROG [20052]');
                case 20053
                    warndlg('DRV_FLEXERROR [20053]');
                case 20054
                    warndlg('DRV_GPIBERROR [20054]');
                case 20055
                    warndlg('DRV_EEPROMVERSIONERROR [20055]');
                case 20064
                    warndlg('DRV_DATATYPE [20064]');
                case 20065
                    warndlg('DRV_DRIVER_ERRORS [20065]');
                case 20066
                    warndlg('DRV_P1INVALID [20066]');
                case 20067
                    warndlg('DRV_P2INVALID [20067]');
                case 20068
                    warndlg('DRV_P3INVALID [20068]');
                case 20069
                    warndlg('DRV_P4INVALID [20069]');
                case 20070
                    warndlg('DRV_INIERROR [20070]');
                case 20071
                    warndlg('DRV_COFERROR [20071]');
                case 20072
                    warndlg('DRV_ACQUIRING [20072]');
                case 20073
                    warndlg('DRV_IDLE [20073]');
                case 20074
                    warndlg('DRV_TEMPCYCLE [20074]');
                case 20075
                    warndlg('DRV_NOT_INITIALIZED [20075]');
                case 20076
                    warndlg('DRV_P5INVALID [20076]');
                case 20077
                    warndlg('DRV_P6INVALID [20077]');
                case 20078
                    warndlg('DRV_INVALID_MODE [20078]');
                case 20079
                    warndlg('DRV_INVALID_FILTER [20079]');
                case 20080
                    warndlg('DRV_I2CERRORS [20080]');
                case 20081
                    warndlg('DRV_I2CDEVNOTFOUND [20081]');
                case 20082
                    warndlg('DRV_I2CTIMEOUT [20082]');
                case 20083
                    warndlg('DRV_P7INVALID [20083]');
                case 20084
                    warndlg('DRV_P8INVALID [20084]');
                case 20085
                    warndlg('DRV_P9INVALID [20085]');
                case 20086
                    warndlg('DRV_P10INVALID [20086]');
                case 20087
                    warndlg('DRV_P11INVALID [20087]');
                case 20089
                    warndlg('DRV_USBERROR [20089]');
                case 20090
                    warndlg('DRV_IOCERROR [20090]');
                case 20091
                    warndlg('DRV_VRMVERSIONERROR [20091]');
                case 20092
                    warndlg('DRV_GATESTEPERROR [20092]');
                case 20093
                    warndlg('DRV_USB_INTERRUPT_ENDPOINT_ERROR [20093]');
                case 20094
                    warndlg('DRV_RANDOM_TRACK_ERROR [20094]');
                case 20095
                    warndlg('DRV_INVALID_TRIGGER_MODE [20095]');
                case 20096
                    warndlg('DRV_LOAD_FIRMWARE_ERROR [20096]');
                case 20097
                    warndlg('DRV_DIVIDE_BY_ZERO_ERROR [20097]');
                case 20098
                    warndlg('DRV_INVALID_RINGEXPOSURES [20098]');
                case 20099
                    warndlg('DRV_BINNING_ERROR [20099]');
                case 20100
                    warndlg('DRV_INVALID_AMPLIFIER [20100]');
                case 20101
                    warndlg('DRV_INVALID_COUNTCONVERT_MODE [20101]');
                case 20990
                    warndlg('DRV_ERROR_NOCAMERA [20990]');
                case 20991
                    warndlg('DRV_NOT_SUPPORTED [20991]');
                case 20992
                    warndlg('DRV_NOT_AVAILABLE [20992]');
                case 20115
                    warndlg('DRV_ERROR_MAP [20115]');
                case 20116
                    warndlg('DRV_ERROR_UNMAP [20116]');
                case 20117
                    warndlg('DRV_ERROR_MDL [20117]');
                case 20118
                    warndlg('DRV_ERROR_UNMDL [20118]');
                case 20119
                    warndlg('DRV_ERROR_BUFFSIZE [20119]');
                case 20121
                    warndlg('DRV_ERROR_NOHANDLE [20121]');
                case 20130
                    warndlg('DRV_GATING_NOT_AVAILABLE [20130]');
                case 20131
                    warndlg('DRV_FPGA_VOLTAGE_ERROR [20131]');
                case 20150
                    warndlg('DRV_OW_CMD_FAIL [20150]');
                case 20151
                    warndlg('DRV_OWMEMORY_BAD_ADDR [20151]');
                case 20152
                    warndlg('DRV_OWCMD_NOT_AVAILABLE [20152]');
                case 20153
                    warndlg('DRV_OW_NO_SLAVES [20153]');
                case 20154
                    warndlg('DRV_OW_NOT_INITIALIZED [20154]');
                case 20155
                    warndlg('DRV_OW_ERROR_SLAVE_NUM [20155]');
                case 20156
                    warndlg('DRV_MSTIMINGS_ERROR [20156]');
                case 20173
                    warndlg('DRV_OA_NULL_ERROR [20173]');
                case 20174
                    warndlg('DRV_OA_PARSE_DTD_ERROR [20174]');
                case 20175
                    warndlg('DRV_OA_DTD_VALIDATE_ERROR [20175]');
                case 20176
                    warndlg('DRV_OA_FILE_ACCESS_ERROR [20176]');
                case 20177
                    warndlg('DRV_OA_FILE_DOES_NOT_EXIST [20177]');
                case 20178
                    warndlg('DRV_OA_XML_INVALID_OR_NOT_FOUND_ERROR [20178]');
                case 20179
                    warndlg('DRV_OA_PRESET_FILE_NOT_LOADED [20179]');
                case 20180
                    warndlg('DRV_OA_USER_FILE_NOT_LOADED [20180]');
                case 20181
                    warndlg('DRV_OA_PRESET_AND_USER_FILE_NOT_LOADED [20181]');
                case 20182
                    warndlg('DRV_OA_INVALID_FILE [20182]');
                case 20183
                    warndlg('DRV_OA_FILE_HAS_BEEN_MODIFIED [20183]');
                case 20184
                    warndlg('DRV_OA_BUFFER_FULL [20184]');
                case 20185
                    warndlg('DRV_OA_INVALID_STRING_LENGTH [20185]');
                case 20186
                    warndlg('DRV_OA_INVALID_CHARS_IN_NAME [20186]');
                case 20187
                    warndlg('DRV_OA_INVALID_NAMING [20187]');
                case 20188
                    warndlg('DRV_OA_GET_CAMERA_ERROR [20188]');
                case 20189
                    warndlg('DRV_OA_MODE_ALREADY_EXISTS [20189]');
                case 20190
                    warndlg('DRV_OA_STRINGS_NOT_EQUAL [20190]');
                case 20191
                    warndlg('DRV_OA_NO_USER_DATA [20191]');
                case 20192
                    warndlg('DRV_OA_VALUE_NOT_SUPPORTED [20192]');
                case 20193
                    warndlg('DRV_OA_MODE_DOES_NOT_EXIST [20193]');
                case 20194
                    warndlg('DRV_OA_CAMERA_NOT_SUPPORTED [20194]');
                case 20195
                    warndlg('DRV_OA_FAILED_TO_GET_MODE [20195]');
                case 20211
                    warndlg('DRV_PROCESSING_FAILED [20211]');
                otherwise
                    warndlg('Andor SDK Returned Error: UNKNOWN ERROR [%d]',code);
            end
        end
        
        
        function I = LiveMode(app)
            switch(app.ModeDropDown.Value)
                case 'Image'
                    XPix = app.XPixels/app.HBinSpinner.Value;
                    YPix = app.YPixels/app.VBinSpinner.Value;
                case 'RandomTrack'
                    HBin = app.HBinSpinner.Value;
                    XPix = app.XPixels/HBin;
                    YPix = size(app.UITable.Data,1);
%                     %% instantiate the library
%                     disp('Loading library...');
%                     lib = lsl_loadlib();
%                     
%                     % make a new stream outlet
%                     disp('Creating a new streaminfo...');
%                     info = lsl_streaminfo(lib,'AndorCam','rndTrk',XPix*YPix,0,'cf_int32','sdfwerr32432');
%                     
%                     disp('Opening an outlet...');
%                     outlet = lsl_outlet(info);
                   
            end
            
            
            while(app.loopContinue)
                
                [ret] = StartAcquisition();
                DispError(app,ret)
                
                %wait for accumulation to complete
                [ret, status] = AndorGetStatus();
                while(status == atmcd.DRV_ACQUIRING)
                    [ret, status] = AndorGetStatus();
                end
                
                [ret, imageData] = GetMostRecentImage(XPix * YPix);
                DispError(app,ret)
                
                if ret == atmcd.DRV_SUCCESS
                    %display the acquired image
                    %I=flipdim(transpose(reshape(imageData, XPix, YPix)),1);
                    I=transpose(reshape(imageData, XPix, YPix));
                    
                    switch(app.ModeDropDown.Value)
                        case 'Image'
                            app.ima.CData = I;
                            %                             imagesc(app.UIAxes,I)
                            %                             colormap(app.UIAxes,gray);
                        case 'RandomTrack'
                            for i = 1:YPix
                                app.ima(i).YData = I(i,:);
                                app.ima(i).Color = app.colormap_arr(i,:);
                            end
%                             outlet.push_sample(double(imageData));
%                             
%                         case 'DMS' %Pan modified
%                             for i = 1:YPix
%                                 app.ima(i).YData = I(i,:);
%                             end
%                             
%                         case 'Laser' %Pan modified
%                             for i = 1:YPix
%                                 app.ima(i).YData = I(i,:);
%                             end
                    end
                    drawnow;
                end
                if strcmpi(app.LiveModeSwitch.Value,'off')
                    app.loopContinue = 0;
                end
            end
        end


        function [baselineShort] = Baseline(app)
            HBin = app.HBinSpinner.Value;
                    XPix = app.XPixels;
                    YPix = size(app.UITable.Data,1);
                    assignin("base","YPix",YPix)
                    assignin("base","XPix",XPix)
                    samples = app.SamplesSpinner.Value;
                    i = 1;
            while(true)
                %disp('Starting Acquisition');
                [ret] = StartAcquisition();
                DispError(app,ret);
                
                %wait for accumulation to complete
                [ret, status] = AndorGetStatus();
                while(status == atmcd.DRV_ACQUIRING)
                    [ret, status] = AndorGetStatus();
                end
                
                %get the acquired image
                [ret, imageData] = GetMostRecentImage(XPix/HBin * YPix);
                DispError(app,ret)
                if ret == atmcd.DRV_SUCCESS
                    I =(flipdim(transpose(reshape(imageData, XPix/HBin, YPix)),1));
                end
                assignin('base',"I",I)
                if i <= samples
                    dShort(:,:,i) = double(I);
                    assignin("base","dShort",dShort)
                    %dLong(:,i) = double(I(1,:)');
                    i = i+1;
                end
                if size(dShort,3)==samples
                    cvShort = std(dShort,0,3)./mean(dShort,3)*100;
                    assignin("base","cvShort",cvShort)
                    mCVShort = mean(cvShort(:,48:105),2); % wavelength 701.0948~901.3130nm
                    assignin("base","mCVShort",mCVShort)
                    for i=1:YPix
                        app.ima(i).YData=horzcat(app.ima(i).YData(2:15), mCVShort(i));
                    end
                    
                    %cvLong = std(dLong,0,2)./mean(dLong,2)*100;
                    %mCVLong = mean(cvLong(48:105)); % wavelength 701.0948~901.3130nm
                    %app.ima(1).YData = horzcat(app.ima(1).YData(2:100),mCVLong);
                    i = samples;
                    baselineShort = dShort/app.AccumulationsSpinner.Value;
                    %baselineLong = dLong/samples;
                    dShort = dShort(:,:,2:samples);
                    %dLong = dLong(:,2:samples);
                    warndlg('Baseline Finished!!');
                    break;
                end
                pause(app.CycleTimesSpinner.Value)
            end
        end
        
        
        function laser(app)
            mkdir([app.TargetFolderEditField.Value '\Laser'])
            HBin = app.HBinSpinner.Value;
            XPix = app.XPixels;
            YPix = size(app.UITable.Data,1);
            
            
            % Get baseline duration from user
            prompt = {'Baseline time (s)','Background time (s)'};
            dlgtitle = 'Laser';
            dims = [1 40];
            definput = {'60','15'}; %Pan modified BG default to 15sec
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            baselineTime = str2num(answer{1});
            backgroundTime = str2num(answer{2});
            
            % Get laser baseline
            if baselineTime ~= 0
                uiwait(msgbox('Press ok to begin baseline'))
                [ret] = StartAcquisition();
                DispError(app,ret);
                h = waitbar(0,'Starting Acquisition');
                start = GetSecs();
                elapsedTime=0;
                while elapsedTime < baselineTime
                    currentTime = GetSecs();
                    elapsedTime = currentTime-start;
                    x = baselineTime-elapsedTime;
                    waitbar(elapsedTime/baselineTime,h,['baseline : ' num2str(ceil(x)) 's left'])
                end
                [ret] = AbortAcquisition();
                DispError(app,ret);
                [ret, frameCount] = GetTotalNumberImagesAcquired();
                DispError(app,ret);
                currentSeries = 1;
                I = [];
                while(currentSeries <= frameCount)
                    
                    [ret, imageData] = GetOldestImage(XPix/HBin * YPix);
                    
                    if ret == atmcd.DRV_SUCCESS % data returned
                        I(:,:,currentSeries) = flipdim(transpose(reshape(imageData, XPix/HBin, YPix)),1);
                        currentSeries=currentSeries+1;
                    end
                end
                save([app.TargetFolderEditField.Value '\Laser\baselineLaser.mat'],'I','-mat');
                close(h)
            end
            
            % Start Laser Protocol
            for i = 1:app.TotalLaserTimeminSpinner.Value+1
                if i == app.TotalLaserTimeminSpinner.Value+1
                    nSecs = app.RecoveryTimeminSpinner.Value*60;
                    fileName = '\Laser\recovery.mat';
                    msg = 'Recovery';
                else
                    nSecs = 5;
                    %nSecs = 600;
                    fileName = ['\Laser\laser' num2str(i) '.mat'];
                    msg = ['Laser ' num2str(i)];
                end
                
                % Start Laser stimulation
                uiwait(msgbox(['Press ok to begin ' msg]))
                startTime = clock;
                [ret] = StartAcquisition();
                DispError(app,ret);
                
                h = waitbar(0,'Starting Acquisition');
                elapsedTime = 0;
                start = GetSecs();
                while elapsedTime < nSecs
                    currentTime = GetSecs();
                    elapsedTime = currentTime-start;
                    x = nSecs-elapsedTime;
                    waitbar(elapsedTime/nSecs,h,[msg ' : ' num2str(ceil(x)) 's left'])
                end
                
                [ret] = AbortAcquisition();
                DispError(app,ret);
                stopTime = clock;
                [ret, frameCount] = GetTotalNumberImagesAcquired();
                DispError(app,ret);
                currentSeries = 1;
                I = [];
                while(currentSeries <= frameCount)
                    
                    [ret, imageData] = GetOldestImage(XPix/HBin * YPix);
                    
                    if ret == atmcd.DRV_SUCCESS % data returned
                        I(:,:,currentSeries) = flipdim(transpose(reshape(imageData, XPix/HBin, YPix)),1);
                        currentSeries=currentSeries+1;
                    end
                end
                save([app.TargetFolderEditField.Value fileName],'I','startTime','stopTime','-mat');
                close(h)
            end
            
            % Get laser background
            if backgroundTime ~= 0
                uiwait(msgbox('Press ok to begin background'))
                [ret] = StartAcquisition();
                DispError(app,ret);
                h = waitbar(0,'Starting Acquisition');
                start = GetSecs();
                elapsedTime=0;
                while elapsedTime < backgroundTime
                    currentTime = GetSecs();
                    elapsedTime = currentTime-start;
                    x = backgroundTime-elapsedTime;
                    waitbar(elapsedTime/backgroundTime,h,['background : ' num2str(ceil(x)) 's left'])
                end
                [ret] = AbortAcquisition();
                DispError(app,ret);
                [ret, frameCount] = GetTotalNumberImagesAcquired();
                DispError(app,ret);
                currentSeries = 1;
                I = [];
                while(currentSeries <= frameCount)
                    
                    [ret, imageData] = GetOldestImage(XPix/HBin * YPix);
                    
                    if ret == atmcd.DRV_SUCCESS % data returned
                        I(:,:,currentSeries) = flipdim(transpose(reshape(imageData, XPix/HBin, YPix)),1);
                        currentSeries=currentSeries+1;
                    end
                end
                save([app.TargetFolderEditField.Value '\Laser\backgroundLaser.mat'],'I','-mat');
                close(h)
            end
            [~, results.Exposure, results.Accumulate, results.Kinetic] = GetAcquisitionTimings();
            save([app.TargetFolderEditField.Value '\Laser\LaserResult.mat'],'results','-mat');
        end
        
        
        function LSLAndor(app)
            HBin = app.HBinSpinner.Value;
            XPix = app.XPixels/HBin;
            YPix = size(app.UITable.Data,1);
            
            % Pixels from 700-900 nm
            pixel_bin_1 = round((1118.3-700)/(0.5448 * HBin));
            pixel_bin_2 = round((1118.3-900)/(0.5448 * HBin));
            
            dat = ones(5,XPix*YPix);
            figure;
            xlabel('time(sec)');
            ylabel('CV value');
            ylim([0 0.05]);
            h = animatedline;
            tstart = tic;
            tic;
            tstep = 0;
            while(app.loopContinue)
                
                if tstep > 1
                    cul_dat = [];
                    for i=1:YPix
                        cul_dat = [cul_dat dat(:,pixel_bin_2*i:pixel_bin_1*i)];
                    end
                    cv = max(std(cul_dat,0,1)/mean(cul_dat,1));
%                     cv = max(std(dat,0,1)/mean(dat,1));
                    title(['cv = ' num2str(cv)])
                    addpoints(h,tend,cv);
                    drawnow
                    tic;
                end
                
                [ret] = StartAcquisition();
                DispError(app,ret)
                
                %wait for accumulation to complete
                [ret, status] = AndorGetStatus();
                while(status == atmcd.DRV_ACQUIRING)
                    [ret, status] = AndorGetStatus();
                end
                
                [ret, imageData] = GetMostRecentImage(XPix * YPix);
                DispError(app,ret)
                
%                 disp(size(imageData));
                if ret == atmcd.DRV_SUCCESS
                    dat = [dat(2:5,:); transpose(double(imageData))];
%                     outlet.push_sample(double(imageData));
                end
                
                [ ~, ~, keyCode ] = KbCheck;
                if strcmp(KbName(keyCode),'w')
                    AbortAcquisition();
                    break;
                end
                
                if tstep == 0
                    for i=1:5
                        dat(i,:)=transpose(double(imageData));
                    end
                end
                
                tstep = toc;
                tend = toc(tstart);
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ret=AndorInitialize('');
            DispError(app,ret)
            [ret]=CoolerON();
            DispError(app,ret)
            [ret] = SetTemperature(app.TemperatureSpinner.Value);
            DispError(app,ret)
            [ret]=SetShutterEx(1, 1, app.ShutterCloseSpinner.Value, app.ShutterOpenSpinner.Value, 0);
            DispError(app,ret)
            [ret]=SetExposureTime(0.02);
            DispError(app,ret)
            [ret]=SetAcquisitionMode(1);
            DispError(app,ret)
            [ret]=SetReadMode(4);
            DispError(app,ret)
            [ret]=SetTriggerMode(0);
            DispError(app,ret)
            [ret, app.XPixels, app.YPixels]=GetDetector;
            DispError(app,ret)
            [ret]=SetImage(app.HBinSpinner.Value, app.VBinSpinner.Value, 1, app.XPixels, 1, app.YPixels);
            DispError(app,ret)
            [ret]=SetBaselineClamp(0);
            DispError(app,ret)
            
            if isfile('tableData.mat')
                tableData=load('tableData.mat');
                app.UITable.Data=tableData.tableData;
            else
                app.UITable.Data=[1 100;101 200];
            end

            I = zeros(app.YPixels,app.XPixels);
            app.ima = imagesc(app.UIAxes,I);
            colormap(app.UIAxes,gray);
            colorbar(app.UIAxes)
        end

        % Button pushed function: SetTempButton
        function SetTempButtonPushed(app, event)
            ret = SetTemperature(app.TemperatureSpinner.Value);
            DispError(app,ret)
        end

        % Button pushed function: GetTempButton
        function GetTempButtonPushed(app, event)
            [ret,temp]=GetTemperature();
            DispError(app,ret)
            if ret==atmcd.DRV_TEMPERATURE_STABILIZED
                app.Lamp.Color='g';
            else
                app.Lamp.Color = 'r';
            end
            app.CurrentTemperatureGauge.Value = temp;
            
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            switch(value)
                case 'Image'
                    app.VBinSpinner.Enable=1;
                    app.GetBGButton.Enable=1;
                    I = zeros(app.YPixels,app.XPixels);
                    app.ima = imagesc(app.UIAxes,I);
                    colormap(app.UIAxes,gray);
                    colorbar(app.UIAxes);
                    [ret]=SetAcquisitionMode(1);
                    DispError(app,ret)
                    [ret]=SetReadMode(4);
                    DispError(app,ret)
                    [ret]=SetImage(app.HBinSpinner.Value, app.VBinSpinner.Value, 1, app.XPixels, 1, app.YPixels);
                    DispError(app,ret)
                    legend(app.UIAxes,'off');
                case 'RandomTrack'
                    app.VBinSpinner.Enable=0;
                    app.GetBGButton.Enable=1;
                    HBin = app.HBinSpinner.Value;
                    XPix = app.XPixels/HBin;
                    YPix = size(app.UITable.Data,1);
                    app.UIAxes.YDir='normal';
                    colorbar(app.UIAxes,'off')
                    ntracks = YPix;
                    app.ntracksloc = reshape(app.UITable.Data',1,[]);
                    [ret]=SetAcquisitionMode(1);
                    DispError(app,ret)
                    [ret]=SetReadMode(2);
                    DispError(app,ret)
                    [ret]=SetCustomTrackHBin(app.HBinSpinner.Value);
                    DispError(app,ret)
                    [ret]=SetRandomTracks(ntracks,app.ntracksloc);
                    DispError(app,ret)
                case 'Baseline'
                    app.VBinSpinner.Enable=0;
                    app.GetBGButton.Enable=0;
                    app.ntracksloc = reshape(app.UITable.Data',1,[]);
                    [ret]=SetAcquisitionMode(2);                  %   Set acquisition mode; 2 for Accumulate
                    DispError(app,ret)
                    [ret]=SetNumberAccumulations(app.AccumulationsSpinner.Value);
                    DispError(app,ret)
                    [ret]=SetReadMode(2);
                    DispError(app,ret)
                    [ret]=SetCustomTrackHBin(app.HBinSpinner.Value);
                    DispError(app,ret)
                    [ret]=SetRandomTracks(size(app.UITable.Data,1),app.ntracksloc);
                    DispError(app,ret)
                case 'DMS'
                    app.VBinSpinner.Enable=0;
                    [ret]=SetAcquisitionMode(5);                  %   Set acquisition mode; 5 for run until abort
                    DispError(app,ret);
                    [ret]=SetKineticCycleTime(app.KineticCycleTimesSpinner_2.Value);
                    DispError(app,ret);
                    [ret]=SetReadMode(2); % Set read mode; 2 for RandomTrack
                    DispError(app,ret)
                    [ret]=SetCustomTrackHBin(app.HBinSpinner.Value);
                    DispError(app,ret)
                    [ret]=SetRandomTracks(size(app.UITable.Data,1),app.ntracksloc);
                    DispError(app,ret)
                    app.GetBGButton.Enable=0;
                    assignin('base','x',app.UITable.Data)
                case 'Laser'
                    app.VBinSpinner.Enable=0;
                    [ret]=SetAcquisitionMode(5);                  %   Set acquisition mode; 5 for run until abort
                    DispError(app,ret);
                    [ret]=SetKineticCycleTime(app.KineticCycleTimesSpinner.Value);
                    DispError(app,ret);
                    [ret]=SetReadMode(2); % Set read mode; 2 for RandomTrack
                    DispError(app,ret)
                    [ret]=SetCustomTrackHBin(app.HBinSpinner.Value);
                    DispError(app,ret)
                    [ret]=SetRandomTracks(size(app.UITable.Data,1),app.ntracksloc);
                    DispError(app,ret)
                    app.GetBGButton.Enable=0;
                case 'Stroop'
                    app.VBinSpinner.Enable=0;
                    [ret]=SetAcquisitionMode(5);                  %   Set acquisition mode; 5 for run until abort
                    DispError(app,ret);
                    [ret]=SetKineticCycleTime(app.KineticCycleTimesSpinner_2.Value);
                    DispError(app,ret);
                    [ret]=SetReadMode(2); % Set read mode; 2 for RandomTrack
                    DispError(app,ret)
                    [ret]=SetCustomTrackHBin(app.HBinSpinner.Value);
                    DispError(app,ret)
                    [ret]=SetRandomTracks(size(app.UITable.Data,1),app.ntracksloc);
                    DispError(app,ret)
                    app.GetBGButton.Enable=0;
                case 'Card Sorting'
                    app.VBinSpinner.Enable=0;
                    [ret]=SetAcquisitionMode(5);                  %   Set acquisition mode; 5 for run until abort
                    DispError(app,ret);
                    [ret]=SetKineticCycleTime(app.KineticCycleTimesSpinner.Value);
                    DispError(app,ret);
                    [ret]=SetReadMode(2); % Set read mode; 2 for RandomTrack
                    DispError(app,ret)
                    [ret]=SetCustomTrackHBin(app.HBinSpinner.Value);
                    DispError(app,ret)
                    [ret]=SetRandomTracks(size(app.UITable.Data,1),app.ntracksloc);
                    DispError(app,ret)
                    app.GetBGButton.Enable=0;
                    
                case 'LSL'
                    [ret]=SetAcquisitionMode(1);
                    DispError(app,ret)
                    [ret]=SetReadMode(2);
                    DispError(app,ret)
                    [ret]=SetCustomTrackHBin(app.HBinSpinner.Value);
                    DispError(app,ret)
                    [ret]=SetRandomTracks(size(app.UITable.Data,1),app.ntracksloc);
                    DispError(app,ret)


            end
        end

        % Value changed function: HBinSpinner
        function HBinSpinnerValueChanged(app, event)
            value = app.HBinSpinner.Value;
            switch(app.ModeDropDown.Value)
                case 'Image'
                    [ret]=SetImage(app.HBinSpinner.Value, app.VBinSpinner.Value, 1, app.XPixels, 1, app.YPixels);
                    DispError(app,ret)
                case{'RandomTrack','Baseline','DMS','Laser'}
                    [ret]=SetCustomTrackHBin(value);
                    DispError(app,ret)
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if strcmpi(app.LiveModeSwitch.Value,'On')
                app.ExposuresSpinner.Enable=0;
                app.HBinSpinner.Enable=0;
                app.ShutterCloseSpinner.Enable=0;
                app.ShutterOpenSpinner.Enable=0;
                app.ModeDropDown.Enable=0;
                app.SetButton.Enable=0;
                app.GetTempButton.Enable=0;
                app.SetTempButton.Enable=0;
                app.CoolerSwitch.Enable=0;
                app.StartButton.Enable=0;
                app.LiveModeSwitch.Enable=0;
                app.GetBGButton.Enable=0;
                app.SaveButton_2.Enable=0;
            end
            
            switch(app.ModeDropDown.Value)
                case {'Image','RandomTrack'}
                    app.loopContinue=1;
                    if strcmpi(app.ModeDropDown.Value,'RandomTrack')
                        app.UITable.Data = reshape(app.ntracksloc,2,[])';
                        HBin = app.HBinSpinner.Value;
                        XPix = app.XPixels/HBin;
                        YPix = size(app.UITable.Data,1);
                        I = zeros(YPix,XPix);
                        app.UIAxes.YDir='normal';
                        colorbar(app.UIAxes,'off')
                        legend(app.UIAxes)
                        app.colormap_arr=jet(size(app.UITable.Data,1));
                        app.ima = plot(app.UIAxes,I');
                    end
                    app.I = LiveMode(app);
                case 'Baseline'
                    app.ima = plot(app.UIAxes,ones(size(app.UITable.Data,1),15)');
                    app.UIAxes.YDir='normal';
                    xlim(app.UIAxes,[1 20]);
                    app.loopContinue=1;
                    app.UITable.Data = reshape(app.ntracksloc,2,[])';
                    [baselineShort] = Baseline(app);
                    if isempty(app.folderPath)
                        app.folderPath = uigetdir;
                        app.TargetFolderEditField.Value=app.folderPath;
                    end
                    folderNumber=1;
                    while 1
                        baselineFolder = [app.TargetFolderEditField.Value '\baseline_' num2str(folderNumber)];
                        if ~exist(baselineFolder,'dir')
                            mkdir(baselineFolder)
                            break
                        end
                        folderNumber=folderNumber+1;
                    end
                    save([baselineFolder '\baseline.mat'],'baselineShort');
                    xlim(app.UIAxes,'auto')
                case 'DMS'
                    HBin = app.HBinSpinner.Value;
                    XPix = app.XPixels;
                    YPix = size(app.UITable.Data,1);
                        
                    if isempty(app.folderPath)
                        app.folderPath = uigetdir;
                        app.TargetFolderEditField.Value=app.folderPath;
                    end
                    app.UITable.Data = reshape(app.ntracksloc,2,[])';
                    answer = questdlg('Choose DMS mode', ...
                    	'DMS', ...
                    	'Practice','DMS','Background','DMS');
                    % Handle response
                    switch answer
                        case 'Practice'
                            DMSmode = 1;
                        case 'DMS'
                            DMSmode = 2;
                        case 'Background'
                            DMSmode = 3;
                    end
                    [results] = PTBDMSV2(app.StimulusSpinner.Value,app.RetentionSpinner.Value,app.ProbeSpinner.Value,app.ITISpinner.Value,app.LayoutSpinner.Value,app.TrialsSpinner.Value,app.TargetFolderEditField.Value,XPix,HBin,YPix,DMSmode);
                    x = [];
                    y =[];
                    for i = 1:length(results.LR)
                        t = results.TrialStartTime(i);
                        x = [x t t];
                        t2 = results.ResponseTime(i)+t;
                        x = [x t2 t2];
                        if results.Correct(i)
                            cor = 1;
                        else
                            cor = -1;
                        end
                        y = [y 0 cor cor 0];
                    end
                    plot(app.UIAxes,x,y)
                case 'Laser'
                    if isempty(app.folderPath)
                        app.folderPath = uigetdir;
                        app.TargetFolderEditField.Value=app.folderPath;
                    end
                    laser(app);
                case 'Stroop'
                    HBin = app.HBinSpinner.Value;
                    XPix = app.XPixels;
                    YPix = size(app.UITable.Data,1);
                    PTBStroop(XPix,HBin,YPix);
%                     [respMat] = PTBStroop(XPix,HBin,YPix);
                    assignin("base","respMat",'respMat')
                case 'Card Sorting'
                    HBin = app.HBinSpinner.Value;
                    XPix = app.XPixels;
                    YPix = size(app.UITable.Data,1);
                    if isempty(app.folderPath)
                        app.folderPath = uigetdir;
                        app.TargetFolderEditField.Value=app.folderPath;
                    end
                    answer = questdlg('Choose Card Sorting mode', ...
                    	'Card Sorting', ...
                    	'Practice','Card Sorting','Card Sorting');
                    % Handle response
                    switch answer
                        case 'Practice'
                            CSmode = 1;
                        case 'Card Sorting'
                            CSmode = 2;
                    end
                    MWCST_V4(XPix,HBin,YPix,app.TargetFolderEditField.Value,CSmode);
                    assignin("base","respMat",'respMat')
                case 'LSL'
                    HBin = app.HBinSpinner.Value;
                    XPix = app.XPixels;
                    YPix = size(app.UITable.Data,1);
                    app.loopContinue=1;
%                     AndorCamLSL(XPix,YPix)
%                     legend(app.UIAxes,'off');
%                     app.ima = animatedline(app.UIAxes);
                    LSLAndor(app);
                    disp('Stream Stopped')
                
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.loopContinue=0;
            app.ExposuresSpinner.Enable=1;
            app.HBinSpinner.Enable=1;
            app.ShutterCloseSpinner.Enable=1;
            app.ShutterOpenSpinner.Enable=1;
            app.ModeDropDown.Enable=1;
            app.SetButton.Enable=1;
            app.GetTempButton.Enable=1;
            app.SetTempButton.Enable=1;
            app.CoolerSwitch.Enable=1;
            app.StartButton.Enable=1;
            app.LiveModeSwitch.Enable=1;
            app.SaveButton_2.Enable=1;
            app.GetBGButton.Enable=1;
            AbortAcquisition();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            [ret]=AbortAcquisition;

            [ret]=AndorShutDown;
            
            delete(app)
            
        end

        % Value changed function: ExposuresSpinner
        function ExposuresSpinnerValueChanged(app, event)
            value = app.ExposuresSpinner.Value;
            ret=SetExposureTime(value);
            DispError(app,ret)
        end

        % Value changed function: ShutterOpenSpinner
        function ShutterOpenSpinnerValueChanged(app, event)
            value = app.ShutterOpenSpinner.Value;
            ret=SetShutterEx(1,1,app.ShutterCloseSpinner.Value,value,0);
            DispError(app,ret)
        end

        % Value changed function: ShutterCloseSpinner
        function ShutterCloseSpinnerValueChanged(app, event)
            value = app.ShutterCloseSpinner.Value;
            ret=SetShutterEx(1,1,value,app.ShutterOpenSpinner.Value,0);
            DispError(app,ret)
        end

        % Value changed function: CoolerSwitch
        function CoolerSwitchValueChanged(app, event)
            value = app.CoolerSwitch.Value;
            switch(value)
                case 'on'
                    ret = CoolerON();
                    DispError(app,ret)
                case 'off'
                    ret = CoolerOff();
                    DispError(app,ret)
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.ntracksloc = reshape(app.UITable.Data',1,[]);
            assignin("base",'ntracksloc',app.ntracksloc)
            assignin("base",'tableData',app.UITable.Data)
            ntracks = size(app.UITable.Data,1);
            ret = SetRandomTracks(ntracks,app.ntracksloc);
            DispError(app,ret)
            app.UITable.Data = reshape(app.ntracksloc,2,[])';
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.folderPath = uigetdir;
            app.TargetFolderEditField.Value=app.folderPath;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file,path] = uiputfile('*.tif');
            IM = uint16(app.I);
            imwrite(IM,[path file]);
        end

        % Button pushed function: GetBGButton
        function GetBGButtonPushed(app, event)
            app.ExposuresSpinner.Enable=0;
            app.HBinSpinner.Enable=0;
            app.ShutterCloseSpinner.Enable=0;
            app.ShutterOpenSpinner.Enable=0;
            app.ModeDropDown.Enable=0;
            app.SetButton.Enable=0;
            app.GetTempButton.Enable=0;
            app.SetTempButton.Enable=0;
            app.CoolerSwitch.Enable=0;
            app.StartButton.Enable=0;
            app.LiveModeSwitch.Enable=0;
            app.GetBGButton.Enable=0;
            app.StopButton.Enable=0;
            app.LiveModeSwitch.Value='Off';
            if strcmpi(app.ModeDropDown.Value,'RandomTrack') 
                app.UITable.Data = reshape(app.ntracksloc,2,[])';                
%             elseif strcmpi(app.ModeDropDown.Value,'DMS')   %Pan modified
%                 app.UITable.Data = reshape(app.ntracksloc,2,[])';
%             elseif strcmpi(app.ModeDropDown.Value,'Laser')  %Pan modified
%                 app.UITable.Data = reshape(app.ntracksloc,2,[])';
            end
            app.I = [];
            for i = 1:5
                app.loopContinue=1;
                BG(:,:,i) = LiveMode(app);
            end
            I = mean(BG,3);
            switch(app.ModeDropDown.Value)
                case 'Image'
                    app.ima.CData = I;
                case 'RandomTrack'
                    app.ima(1).YData = I(1,:);
                    app.ima(2).YData = I(2,:);
%                 case 'DMS' %Pan modified
%                     app.ima(1).YData = I(1,:);
%                     app.ima(2).YData = I(2,:);
%                 case 'Laser' %Pan modified
%                     app.ima(1).YData = I(1,:);
%                     app.ima(2).YData = I(2,:);
            end
            drawnow;
            if isempty(app.folderPath)
                app.folderPath = uigetdir;
                app.TargetFolderEditField.Value=app.folderPath;
            end
            save([app.TargetFolderEditField.Value '\BG.mat'],'I','-mat')
            app.ExposuresSpinner.Enable=1;
            app.HBinSpinner.Enable=1;
            app.ShutterCloseSpinner.Enable=1;
            app.ShutterOpenSpinner.Enable=1;
            app.ModeDropDown.Enable=1;
            app.SetButton.Enable=1;
            app.GetTempButton.Enable=1;
            app.SetTempButton.Enable=1;
            app.CoolerSwitch.Enable=1;
            app.StartButton.Enable=1;
            app.LiveModeSwitch.Enable=1;
            app.GetBGButton.Enable=1;
            app.StopButton.Enable=1;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UITable.Data = [app.UITable.Data;0 0];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.UITable.Data = app.UITable.Data(1:size(app.UITable.Data,1)-1,:);
        end

        % Value changed function: VBinSpinner
        function VBinSpinnerValueChanged(app, event)
            value = app.VBinSpinner.Value;
            switch(app.ModeDropDown.Value)
                case 'Image'
                    [ret]=SetImage(app.HBinSpinner.Value, app.VBinSpinner.Value, 1, app.XPixels, 1, app.YPixels);
                    DispError(app,ret)
                case{'RandomTrack','Baseline','DMS','Laser'}
                    %[ret]=SetCustomTrackHBin(value);
                    %DispError(app,ret)
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            tableData=app.UITable.Data;
            save('tableData.mat','tableData');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.UIAxes = uiaxes(app.AcquisitionTab);
            app.ExposuresSpinnerLabel = uilabel(app.AcquisitionTab);
            app.ExposuresSpinnerLabel.Text = 'Exposure (s)';
            app.ExposuresSpinner = uispinner(app.AcquisitionTab);
            app.ExposuresSpinner.Step = 0.001;
            app.ExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @ExposuresSpinnerValueChanged, true);
            app.ExposuresSpinner.Value = 0.2;
            app.ShutterOpenSpinnerLabel = uilabel(app.AcquisitionTab);
            app.ShutterOpenSpinnerLabel.Text = 'Shutter Open';
            app.ShutterOpenSpinner = uispinner(app.AcquisitionTab);
            app.ShutterOpenSpinner.ValueChangedFcn = createCallbackFcn(app, @ShutterOpenSpinnerValueChanged, true);
            app.ShutterCloseSpinnerLabel = uilabel(app.AcquisitionTab);
            app.ShutterCloseSpinnerLabel.Text = 'Shutter Close';
            app.ShutterCloseSpinner = uispinner(app.AcquisitionTab);
            app.ShutterCloseSpinner.ValueChangedFcn = createCallbackFcn(app, @ShutterCloseSpinnerValueChanged, true);
            app.UITable = uitable(app.AcquisitionTab);
            app.UITable.ColumnName = {'Start'; 'End'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.RandomTrackLabel = uilabel(app.AcquisitionTab);
            app.RandomTrackLabel.Text = 'Random Track';
            app.CurrentTemperatureGaugeLabel = uilabel(app.AcquisitionTab);
            app.CurrentTemperatureGaugeLabel.Text = 'Current Temperature';
            app.CurrentTemperatureGauge = uigauge(app.AcquisitionTab, 'semicircular');
            app.CurrentTemperatureGauge.Limits = [-75 50];
            app.CurrentTemperatureGauge.ScaleDirection = 'counterclockwise';
            app.TemperatureSpinnerLabel = uilabel(app.AcquisitionTab);
            app.TemperatureSpinnerLabel.Text = 'Temperature';
            app.TemperatureSpinner = uispinner(app.AcquisitionTab);
            app.TemperatureSpinner.Value = -50;
            app.SetTempButton = uibutton(app.AcquisitionTab, 'push');
            app.SetTempButton.ButtonPushedFcn = createCallbackFcn(app, @SetTempButtonPushed, true);
            app.SetTempButton.Text = 'Set Temp.';
            app.Lamp = uilamp(app.AcquisitionTab);
            app.GetTempButton = uibutton(app.AcquisitionTab, 'push');
            app.GetTempButton.ButtonPushedFcn = createCallbackFcn(app, @GetTempButtonPushed, true);
            app.GetTempButton.Text = 'Get Temp.';
            app.ModeDropDownLabel = uilabel(app.AcquisitionTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.AcquisitionTab);
            app.ModeDropDown.Items = {'Image', 'Card Sorting', 'RandomTrack', 'Baseline', 'DMS', 'Laser', 'Stroop', 'LSL'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Image';
            app.CoolerSwitchLabel = uilabel(app.AcquisitionTab);
            app.CoolerSwitchLabel.Text = 'Cooler';
            app.CoolerSwitch = uiswitch(app.AcquisitionTab, 'slider');
            app.CoolerSwitch.ValueChangedFcn = createCallbackFcn(app, @CoolerSwitchValueChanged, true);
            app.CoolerSwitch.Value = 'On';
            app.StartButton = uibutton(app.AcquisitionTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.AcquisitionTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.HBinSpinnerLabel = uilabel(app.AcquisitionTab);
            app.HBinSpinnerLabel.Text = 'HBin';
            app.HBinSpinner = uispinner(app.AcquisitionTab);
            app.HBinSpinner.Limits = [1 1600];
            app.HBinSpinner.ValueChangedFcn = createCallbackFcn(app, @HBinSpinnerValueChanged, true);
            app.HBinSpinner.Value = 1;
            app.SetButton = uibutton(app.AcquisitionTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.SaveButton = uibutton(app.AcquisitionTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GetBGButton = uibutton(app.AcquisitionTab, 'push');
            app.GetBGButton.ButtonPushedFcn = createCallbackFcn(app, @GetBGButtonPushed, true);
            app.GetBGButton.Text = 'Get BG';
            app.LiveModeSwitchLabel = uilabel(app.AcquisitionTab);
            app.LiveModeSwitchLabel.Text = 'Live Mode';
            app.LiveModeSwitch = uiswitch(app.AcquisitionTab, 'slider');
            app.Button = uibutton(app.AcquisitionTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.AcquisitionTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.VBinSpinnerLabel = uilabel(app.AcquisitionTab);
            app.VBinSpinnerLabel.Text = 'VBin';
            app.VBinSpinner = uispinner(app.AcquisitionTab);
            app.VBinSpinner.Limits = [1 200];
            app.VBinSpinner.ValueChangedFcn = createCallbackFcn(app, @VBinSpinnerValueChanged, true);
            app.VBinSpinner.Value = 1;
            app.SaveButton_2 = uibutton(app.AcquisitionTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.BaselinePanel = uipanel(app.SettingsTab);
            app.BaselinePanel.Title = 'Baseline';
            app.AccumulationsSpinnerLabel = uilabel(app.BaselinePanel);
            app.AccumulationsSpinnerLabel.Text = 'Accumulations';
            app.AccumulationsSpinner = uispinner(app.BaselinePanel);
            app.AccumulationsSpinner.Limits = [1 100];
            app.AccumulationsSpinner.Value = 5;
            app.CycleTimesSpinnerLabel = uilabel(app.BaselinePanel);
            app.CycleTimesSpinnerLabel.Text = 'Cycle Time (s)';
            app.CycleTimesSpinner = uispinner(app.BaselinePanel);
            app.CycleTimesSpinner.Limits = [0 60];
            app.CycleTimesSpinner.Value = 20;
            app.SamplesSpinnerLabel = uilabel(app.BaselinePanel);
            app.SamplesSpinnerLabel.Text = 'Samples';
            app.SamplesSpinner = uispinner(app.BaselinePanel);
            app.SamplesSpinner.Limits = [1 100];
            app.SamplesSpinner.Value = 5;
            app.DMSPanel = uipanel(app.SettingsTab);
            app.DMSPanel.Title = 'DMS';
            app.KineticCycleTimesSpinner_2Label = uilabel(app.DMSPanel);
            app.KineticCycleTimesSpinner_2Label.Text = 'Kinetic Cycle Time (s)';
            app.KineticCycleTimesSpinner_2 = uispinner(app.DMSPanel);
            app.StimulusSpinnerLabel = uilabel(app.DMSPanel);
            app.StimulusSpinnerLabel.Text = 'Stimulus';
            app.StimulusSpinner = uispinner(app.DMSPanel);
            app.StimulusSpinner.Limits = [1 Inf];
            app.StimulusSpinner.Value = 4;
            app.RetentionSpinnerLabel = uilabel(app.DMSPanel);
            app.RetentionSpinnerLabel.Text = 'Retention';
            app.RetentionSpinner = uispinner(app.DMSPanel);
            app.RetentionSpinner.Limits = [1 Inf];
            app.RetentionSpinner.Value = 5;
            app.ProbeSpinnerLabel = uilabel(app.DMSPanel);
            app.ProbeSpinnerLabel.Text = 'Probe';
            app.ProbeSpinner = uispinner(app.DMSPanel);
            app.ProbeSpinner.Limits = [1 Inf];
            app.ProbeSpinner.Value = 5;
            app.ITISpinnerLabel = uilabel(app.DMSPanel);
            app.ITISpinnerLabel.Text = 'ITI';
            app.ITISpinner = uispinner(app.DMSPanel);
            app.ITISpinner.Limits = [1 Inf];
            app.ITISpinner.Value = 8;
            app.LayoutSpinnerLabel = uilabel(app.DMSPanel);
            app.LayoutSpinnerLabel.Text = 'Layout';
            app.LayoutSpinner = uispinner(app.DMSPanel);
            app.LayoutSpinner.Limits = [1 Inf];
            app.LayoutSpinner.Value = 6;
            app.TrialsSpinnerLabel = uilabel(app.DMSPanel);
            app.TrialsSpinnerLabel.Text = 'Trials';
            app.TrialsSpinner = uispinner(app.DMSPanel);
            app.TrialsSpinner.Limits = [1 Inf];
            app.TrialsSpinner.Value = 30;
            app.LaserPanel = uipanel(app.SettingsTab);
            app.LaserPanel.Title = 'Laser';
            app.KineticCycleTimesSpinnerLabel = uilabel(app.LaserPanel);
            app.KineticCycleTimesSpinnerLabel.Text = 'Kinetic Cycle Time (s)';
            app.KineticCycleTimesSpinner = uispinner(app.LaserPanel);
            app.TotalLaserTimeminSpinnerLabel = uilabel(app.LaserPanel);
            app.TotalLaserTimeminSpinnerLabel.Text = 'Total Laser Time (min)';
            app.TotalLaserTimeminSpinner = uispinner(app.LaserPanel);
            app.TotalLaserTimeminSpinner.Limits = [1 Inf];
            app.TotalLaserTimeminSpinner.Value = 10;
            app.RecoveryTimeminSpinnerLabel = uilabel(app.LaserPanel);
            app.RecoveryTimeminSpinnerLabel.Text = 'Recovery Time (min)';
            app.RecoveryTimeminSpinner = uispinner(app.LaserPanel);
            app.RecoveryTimeminSpinner.Limits = [1 Inf];
            app.RecoveryTimeminSpinner.Value = 5;
            app.FilePanel = uipanel(app.SettingsTab);
            app.FilePanel.Title = 'File';
            app.TargetFolderEditFieldLabel = uilabel(app.FilePanel);
            app.TargetFolderEditFieldLabel.Text = 'Target Folder';
            app.TargetFolderEditField = uieditfield(app.FilePanel, 'text');
            app.TargetFolderEditField.Editable = 'off';
            app.OpenButton = uibutton(app.FilePanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AndorDMSV2_LSL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end